classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        AnimateitButton               matlab.ui.control.Button
        HideRevealConstructionButton  matlab.ui.control.Button
        ConverttoSphereButton         matlab.ui.control.Button
        LoadPointsButton              matlab.ui.control.Button
        SavePointsButton              matlab.ui.control.Button
        HideRevealLinesButton         matlab.ui.control.Button
        HideRevealPointsButton        matlab.ui.control.Button
        RunConstructionButton         matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    Points = [];            
    PointHandles        
    LineHandles         
    ConstructionHandles
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function AxesButtonDown(app, event)
    hold(app.UIAxes, 'on');
    
    cp = get(app.UIAxes, 'CurrentPoint');
    x = cp(1,1);
    y = cp(1,2);
    newPoint = [x; y; 1];
    app.Points = [app.Points, newPoint];

    
    h = plot(app.UIAxes, x, y, 'ro', 'MarkerFaceColor', 'r');
    h.HitTest = 'off';
    h.PickableParts = 'none';
    app.PointHandles = [app.PointHandles, h];

                
        end

        % Button pushed function: RunConstructionButton
        function Run(app, event)
if size(app.Points, 2) < 6
    uialert(app.UIFigure, 'You need at least 6 points: 3 for the first 2 circles, 2 for snapping, and 1 for snapping to the bisector.', 'Error');
    return;
end

app.LineHandles = [];

% First Circle
xCircle1 = app.Points(1, end-5);
yCircle1 = app.Points(2, end-5);
xCircumference1 = app.Points(1, end-4);
yCircumference1 = app.Points(2, end-4);
radiusCircle1 = sqrt((xCircumference1 - xCircle1)^2 + (yCircumference1 - yCircle1)^2);

% Second Circle
xCircle2 = app.Points(1, end-3);
yCircle2 = app.Points(2, end-3);
radiusCircle2 = radiusTangentialCircle(xCircle1, yCircle1, xCircumference1, yCircumference1, xCircle2, yCircle2);

if radiusCircle2 <= 0
    uialert(app.UIFigure, 'Invalid second circle. Make sure the 2nd center allows for tangential contact.', 'Error');
    return;
end

% Third Circle Points
xPoint1 = app.Points(1, end-2);
yPoint1 = app.Points(2, end-2);
xPoint2 = app.Points(1, end-1);
yPoint2 = app.Points(2, end-1);

[xOnCircle1, yOnCircle1, select1] = closestPointsOnCircle(xCircle1, yCircle1, radiusCircle1, xCircle2, yCircle2, radiusCircle2, xPoint1, yPoint1);
[xOnCircle2, yOnCircle2, select2] = closestPointsOnCircle(xCircle1, yCircle1, radiusCircle1, xCircle2, yCircle2, radiusCircle2, xPoint2, yPoint2);

if select1 == select2
    uialert(app.UIFigure, 'Selected points must snap to different circles.', 'Error');
    return;
end

% 1st Perpendicular Bisector
[mBisector1, cBisector1] = centreBisector(xCircle1, yCircle1, xCircle2, yCircle2);
xOffBisector = app.Points(1, end);
yOffBisector = app.Points(2, end);
[xOnBisector, yOnBisector] = closestPointBisector(mBisector1, cBisector1, xOffBisector, yOffBisector);

[xCircle3, yCircle3, radiusCircle3] = circleSolve([xOnCircle1, yOnCircle1], [xOnCircle2, yOnCircle2], [xOnBisector, yOnBisector]);

% 2nd Perpendicular Bisector
[mBisector2, cBisector2] = centreBisector(xOnCircle1, yOnCircle1, xOnBisector, yOnBisector);

theta = linspace(0, 2 * pi, 100);
x1 = xCircle1 + radiusCircle1 * cos(theta);
y1 = yCircle1 + radiusCircle1 * sin(theta);
x2 = xCircle2 + radiusCircle2 * cos(theta);
y2 = yCircle2 + radiusCircle2 * sin(theta);
x3 = xCircle3 + radiusCircle3 * cos(theta);
y3 = yCircle3 + radiusCircle3 * sin(theta);

hold(app.UIAxes, 'on');
plot(app.UIAxes, x1, y1, 'b-', 'LineWidth', 1.5,'DisplayName', '1st Circle');
pause(0.5);
plot(app.UIAxes, x2, y2, 'r-', 'LineWidth', 1.5,'DisplayName', '2nd Circle');
pause(0.5);
plot(app.UIAxes, x3, y3, 'm-', 'LineWidth', 1.5, 'DisplayName', '3rd Circle');
pause(0.5);

xBisector1 = linspace(0, 100, 100);
if ~isinf(mBisector1)
    yBisector1 = mBisector1 * xBisector1 + cBisector1;
else
    xBisector1 = cBisector1 * ones(size(xBisector1));
    yBisector1 = linspace(0, 100, 100);
end
plot(app.UIAxes, xBisector1, yBisector1, 'g--', 'LineWidth', 1.2,'DisplayName','1st Perpendicular Bisector');
pause(0.5);

xBisector2 = linspace(0, 100, 100);
if ~isinf(mBisector2)
    yBisector2 = mBisector2 * xBisector2 + cBisector2;
else
    xBisector2 = cBisector2 * ones(size(xBisector2));
    yBisector2 = linspace(0, 100, 100);
end
plot(app.UIAxes, xBisector2, yBisector2, 'c--', 'LineWidth', 1.2,'DisplayName','2nd Perpendicular Bisector');
pause(0.5);

intersections1 = intersections2circles(radiusCircle1, xCircle1, yCircle1, radiusCircle3, xCircle3, yCircle3);
intersections2 = intersections2circles(radiusCircle2, xCircle2, yCircle2, radiusCircle3, xCircle3, yCircle3);

if ~isempty(intersections1)
    plot(app.UIAxes, intersections1(1, 1), intersections1(1, 2), 'kx', 'MarkerSize', 12, 'LineWidth', 2, 'DisplayName', '1st Intersection');
    if size(intersections1, 1) > 1
        plot(app.UIAxes, intersections1(2, 1), intersections1(2, 2), 'kx', 'MarkerSize', 12, 'LineWidth', 2, 'DisplayName', '3rd Intersection');
    end
    pause(0.5);
end
if ~isempty(intersections2)
    plot(app.UIAxes, intersections2(1, 1), intersections2(1, 2), 'kx', 'MarkerSize', 12, 'LineWidth', 2, 'DisplayName', '2nd Intersection');
    if size(intersections2, 1) > 1
        plot(app.UIAxes, intersections2(2, 1), intersections2(2, 2), 'kx', 'MarkerSize', 12, 'LineWidth', 2, 'DisplayName', '4th Intersection');
    end
    pause(0.5);
end

if ~isempty(intersections1) && ~isempty(intersections2)
    xInt1 = intersections1(1, 1);
    yInt1 = intersections1(1, 2);
    xInt2 = intersections2(1, 1);
    yInt2 = intersections2(1, 2);

    [mBisector3, cBisector3] = centreBisector(xInt1, yInt1, xInt2, yInt2);

    xBisector3 = linspace(0, 100, 100);
    if ~isinf(mBisector3)
        yBisector3 = mBisector3 * xBisector3 + cBisector3;
    else
        xBisector3 = cBisector3 * ones(size(xBisector3));
        yBisector3 = linspace(0, 100, 100);
    end
    plot(app.UIAxes, xBisector3, yBisector3, 'k--', 'LineWidth', 1.2,'DisplayName','3rd Perpendicular Bisector');
    pause(0.5);
else
    uialert(app.UIFigure, 'Intersections for one or both circles are missing; cannot plot third bisector.', 'Error');
end

% Task 6
[matrixT, matrixR, matrixCombined] = transformationMatrices(xCircle1, yCircle1, xCircle2, yCircle2, xCircle3, yCircle3);

circle1Points = [x1; y1; ones(size(x1))];
circle2Points = [x2; y2; ones(size(x2))];
circle3Points = [x3; y3; ones(size(x3))];

transformedCircle1 = matrixCombined * circle1Points;
transformedCircle2 = matrixCombined * circle2Points;
transformedCircle3 = matrixCombined * circle3Points;

plot(app.UIAxes, transformedCircle1(1, :), transformedCircle1(2, :), 'b--', 'LineWidth', 1.2,'DisplayName','1st Transformed circle');
pause(0.5);
plot(app.UIAxes, transformedCircle2(1, :), transformedCircle2(2, :), 'r--', 'LineWidth', 1.2,'DisplayName','2nd Transformed circle');
pause(0.5);
plot(app.UIAxes, transformedCircle3(1, :), transformedCircle3(2, :), 'm--', 'LineWidth', 1.2,'DisplayName','3rd Transformed circle');

legend(app.UIAxes, 'show');
hold(app.UIAxes, 'off');
uialert(app.UIFigure, 'All circles, bisectors, & intersections constructed successfully!', 'Success');


        end

        % Button pushed function: HideRevealPointsButton
        function HideRevealPoints(app, event)
         for h = app.PointHandles
            if strcmp(h.Visible, 'on')
                h.Visible = 'off';
            else
                h.Visible = 'on';
            end
        end       
        end

        % Button pushed function: HideRevealLinesButton
        function HideRevealLinesPushed(app, event)

    
    hBisector1 = findobj(app.UIAxes, 'DisplayName', '1st Perpendicular Bisector');
    hBisector2 = findobj(app.UIAxes, 'DisplayName', '2nd Perpendicular Bisector');
    hBisector3 = findobj(app.UIAxes, 'DisplayName', '3rd Perpendicular Bisector');

    
    hBisectors = [hBisector1; hBisector2; hBisector3];
     if ~isempty(hBisectors)
        if strcmp(hBisectors(1).Visible, 'on')
            set(hBisectors, 'Visible', 'off');  
        else
            set(hBisectors, 'Visible', 'on');   
        end
    else
        
        uialert(app.UIFigure, 'No perpendicular bisectors found to toggle.', 'Info');
    end
        
        
        end

        % Button pushed function: SavePointsButton
        function SavePointsPushed(app, event)
   
    if isempty(app.Points)
        uialert(app.UIFigure, 'No points to save.', 'Error');
        return;
    end
    
    
    [file, path] = uiputfile('*.txt', 'Save Points As');
    if isequal(file, 0)
        return; 
    end
    fileName = fullfile(path, file);
    
    
    pointsToSave = app.Points(1:2, :)'; 
    writematrix(pointsToSave, fileName, 'Delimiter', 'space');
    
    uialert(app.UIFigure, 'Points saved successfully!', 'Success');

        
        end

        % Button pushed function: LoadPointsButton
        function LoadPointsPushed(app, event)
    [file, path] = uigetfile('*.txt', 'Select Points File');
    if isequal(file, 0)
        return; 
    end
    fileName = fullfile(path, file);
    
    try
        loadedPoints = dlmread(fileName);
        if size(loadedPoints, 2) ~= 2
            error('Invalid file format.');
        end
        app.Points = [loadedPoints'; ones(1, size(loadedPoints, 1))]; 
    catch
        uialert(app.UIFigure, 'Error loading points. Ensure the file format is correct.', 'Error');
        return;
    end
    cla(app.UIAxes);
    Run(app, event); 


        end

        % Button pushed function: ConverttoSphereButton
        function ConvertToSphereButtonPushed(app, event)
           
    view(app.UIAxes, 3); 
    hold(app.UIAxes, 'on');
    h = findobj(app.UIAxes, 'DisplayName', '3rd Circle');
   if ~isempty(h) && isvalid(h)
       
        xData = h.XData;
        yData = h.YData;
        xCenter = mean(xData);
        yCenter = mean(yData);
        radius = mean(sqrt((xData - xCenter).^2 + (yData - yCenter).^2));
        [X, Y, Z] = sphere(50);
        Xs = xCenter + radius * X;
        Ys = yCenter + radius * Y;
        Zs = radius * Z;
        delete(h);

       
        surf(app.UIAxes, Xs, Ys, Zs, 'EdgeColor', 'none', 'FaceAlpha', 0.6, 'DisplayName', '3rd Circle');
        app.UIAxes.DataAspectRatio = [1 1 1];
        app.UIAxes.DataAspectRatioMode = 'manual';
        shading(app.UIAxes, 'interp');
        camlight(app.UIAxes, 'headlight');
        lighting(app.UIAxes, 'gouraud');

        uialert(app.UIFigure, 'The 3rd circle has been converted into a sphere!', 'Success');
    else
        uialert(app.UIFigure, '3rd circle not found. Run the construction first.', 'Error');
    end

    hold(app.UIAxes, 'off');


        end

        % Button pushed function: HideRevealConstructionButton
        function HideRevealConstructionButtonPushed(app, event)
    hConstructions1 = findobj(app.UIAxes, 'DisplayName', '1st Circle');
    hConstructions2 = findobj(app.UIAxes, 'DisplayName', '2nd Circle');
    hConstructions3 = findobj(app.UIAxes, 'DisplayName', '3rd Circle');
    hConstructions4 = findobj(app.UIAxes, 'DisplayName', '1st Transformed circle');
    hConstructions5 = findobj(app.UIAxes, 'DisplayName', '2nd Transformed circle');
    hConstructions6 = findobj(app.UIAxes, 'DisplayName', '3rd Transformed circle');

    
    hConstructions = [hConstructions1; hConstructions2; hConstructions3;hConstructions4; hConstructions5; hConstructions6 ];

   
    if ~isempty(hConstructions)
        if strcmp(hConstructions(1).Visible, 'on')
            set(hConstructions, 'Visible', 'off');  
        else
            set(hConstructions, 'Visible', 'on');   
        end
    else
        
        uialert(app.UIFigure, 'No constructions found to toggle.', 'Info');
    end

        end

        % Button pushed function: AnimateitButton
        function AnimationButtonPushed(app, event)
    if size(app.Points, 2) < 6
        uialert(app.UIFigure, 'You need at least 6 points to animate.', 'Error');
        return;
    end
    
    delete(findobj(app.UIAxes, '-not', 'MarkerFaceColor', 'r', '-and', 'Type', 'line'));
    Run(app, event);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 100];
            app.UIAxes.YLim = [0 100];
            app.UIAxes.ZLim = [0 100];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @AxesButtonDown, true);
            app.RunConstructionButton = uibutton(app.UIFigure, 'push');
            app.RunConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @Run, true);
            app.RunConstructionButton.Text = 'Run Construction';
            app.HideRevealPointsButton = uibutton(app.UIFigure, 'push');
            app.HideRevealPointsButton.ButtonPushedFcn = createCallbackFcn(app, @HideRevealPoints, true);
            app.HideRevealPointsButton.Text = 'Hide/Reveal Points';
            app.HideRevealLinesButton = uibutton(app.UIFigure, 'push');
            app.HideRevealLinesButton.ButtonPushedFcn = createCallbackFcn(app, @HideRevealLinesPushed, true);
            app.HideRevealLinesButton.Text = 'Hide/Reveal Lines';
            app.SavePointsButton = uibutton(app.UIFigure, 'push');
            app.SavePointsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePointsPushed, true);
            app.SavePointsButton.Text = 'Save Points';
            app.LoadPointsButton = uibutton(app.UIFigure, 'push');
            app.LoadPointsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPointsPushed, true);
            app.LoadPointsButton.Text = 'Load Points';
            app.ConverttoSphereButton = uibutton(app.UIFigure, 'push');
            app.ConverttoSphereButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertToSphereButtonPushed, true);
            app.ConverttoSphereButton.Text = 'Convert to Sphere';
            app.HideRevealConstructionButton = uibutton(app.UIFigure, 'push');
            app.HideRevealConstructionButton.ButtonPushedFcn = createCallbackFcn(app, @HideRevealConstructionButtonPushed, true);
            app.HideRevealConstructionButton.Text = 'Hide/Reveal Construction';
            app.AnimateitButton = uibutton(app.UIFigure, 'push');
            app.AnimateitButton.ButtonPushedFcn = createCallbackFcn(app, @AnimationButtonPushed, true);
            app.AnimateitButton.Text = 'Animate it';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end